classdef DiceGameProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        EndgamePanel             matlab.ui.container.Panel
        GameOverLabel            matlab.ui.control.Label
        PlayerWinsLabel          matlab.ui.control.Label
        ScoreLabel               matlab.ui.control.Label
        UITable_2                matlab.ui.control.Table
        PlayAgainButtonPushed    matlab.ui.control.Button
        Image2_2                 matlab.ui.control.Image
        GamePanel                matlab.ui.container.Panel
        Image5                   matlab.ui.control.Image
        Image6                   matlab.ui.control.Image
        KismetLabel              matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        ClickthediceyouwishtorerollLabel  matlab.ui.control.Label
        Image_3                  matlab.ui.control.Image
        Image_4                  matlab.ui.control.Image
        Image_5                  matlab.ui.control.Image
        RollSelectedButton       matlab.ui.control.Button
        EndTurnButton            matlab.ui.control.Button
        Image_2                  matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        Label_2                  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        CountLabel               matlab.ui.control.Label
        CheckBox                 matlab.ui.control.CheckBox
        CheckBox_2               matlab.ui.control.CheckBox
        CheckBox_3               matlab.ui.control.CheckBox
        CheckBox_4               matlab.ui.control.CheckBox
        CheckBox_5               matlab.ui.control.CheckBox
        TurnLabel                matlab.ui.control.Label
        RoundLabel               matlab.ui.control.Label
        RollAllButton            matlab.ui.control.Button
        PlayerLabel              matlab.ui.control.Label
        StartupPanel             matlab.ui.container.Panel
        Image4                   matlab.ui.control.Image
        StartButton              matlab.ui.control.Button
        EnterNameEditField       matlab.ui.control.EditField
        EnterNameEditFieldLabel  matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        JoinGameButton           matlab.ui.control.Button
        ResetButton              matlab.ui.control.Button
        PlayerListLabel          matlab.ui.control.Label
        WelcometoKismetLabel     matlab.ui.control.Label
        QuitGameButton           matlab.ui.control.Button
        HowToPlayButton          matlab.ui.control.Button
        Image3                   matlab.ui.control.Image
    end

    
    properties (Access = private)
        diceGame = game; % Description
        roller;
        imageFiles;
        readTimer = timer;
        writeTimer = timer;
        tsHandler = thingSpeakHandler;
        localPlayerIdx;
        playerNames;
        showHelp = false;
    end
    
    properties(Dependent, Access = private)
        selectedDice;
    end
    
    methods (Access = private)      
        function turnEnd(app)
            app.Label.Text = num2str(app.roller.rollCount);
            if (app.roller.rollCount == 3)
                app.RollAllButton.Enable = false;
                app.RollSelectedButton.Enable = false;
                app.EndTurnButton.BackgroundColor = [0.0, 1.0, 0.0];
            end
        end
        
        function resetGame(app)
            app.tsHandler.reset()
        end
        
        function checkTurn(app)
            if (~isempty(app.localPlayerIdx) && (app.localPlayerIdx == app.diceGame.turn))
                app.RollAllButton.Enable = "on";
                if(app.roller.rollCount == 0 && app.diceGame.round <= app.diceGame.maxRound)
                    playTurnSound();
                end
            else
                app.RollAllButton.Enable = "off";
            end
            
        end
        function diceImages(app)
            if(app.roller.rollCount == 0)
                app.Image.ImageSource = app.imageFiles(1);
                app.Image_2.ImageSource = app.imageFiles(1);
                app.Image_3.ImageSource = app.imageFiles(1);
                app.Image_4.ImageSource = app.imageFiles(1);
                app.Image_5.ImageSource = app.imageFiles(1);
            else
                vals = app.roller.diceValues;
                app.Image.ImageSource = app.imageFiles(vals(1)+1);
                app.Image_2.ImageSource = app.imageFiles(vals(2)+1);
                app.Image_3.ImageSource = app.imageFiles(vals(3)+1);
                app.Image_4.ImageSource = app.imageFiles(vals(4)+1);
                app.Image_5.ImageSource = app.imageFiles(vals(5)+1);
            end
        end
        
        function updateData(app)
            app.UITable.Data = array2table(app.diceGame.allScores);
            app.checkTurn();
            
            app.RoundLabel.Text = strcat("Round: ", " ", num2str(app.diceGame.round));
            app.TurnLabel.Text = strcat("Turn: ", " ", app.playerNames(app.diceGame.turn));
            %TODO: if not your turn, AKA localPlayerIdx ~=
            %app.diceGame.turn, turn off all roll buttons
        end
        
        function updatePlayers(app, ~, ~)
            [players, round] = app.tsHandler.readNames();
            if(isa(players, 'string'))
                app.playerNames = players;
                app.TextArea.Value = join(app.playerNames, newline);
            end
            
            if(round == 1)
                app.gameStart();
                app.readTimer.stop();
                app.readTimer.TimerFcn = @app.updateScores;
                app.readTimer.start();
            end
        end
        
        function writeScore(app)
            score = scorer(app.roller.diceValues, app.roller.diceColors);
            [bestScore, idx] = score.getBestScore();
            if(~isempty(idx))
                app.diceGame = app.diceGame.setScore(bestScore, idx);
            end
            app.updateData();
            
            app.writeTimer.TimerFcn = @app.tsWriteScore;
            app.writeTimer.start();

        end
    
        
        function updateScores(app, ~, ~)
            [score, idx, round, turn] = app.tsHandler.readScoreIdx();
            
            scoreTest = ~(all(isnan([score, idx])));
            turnTest = (round == app.diceGame.round && turn == app.diceGame.turn);
            
            if(scoreTest && turnTest)
                %Set score with the score/idx that was just assigned
                app.diceGame = app.diceGame.setScore(score, idx);
                app.updateData();
                
                if(app.diceGame.round > app.diceGame.maxRound)
                    app.victoryScreen();
                    %Consider any kind of ThingSpeak cleanup??
                end
            end
        end

        
        function victoryScreen(app)
            app.GamePanel.Enable = 'off';
            app.GamePanel.Visible = 'off';
            app.EndgamePanel.Enable = 'on';
            app.EndgamePanel.Visible = 'on';
            app.UITable_2.Data = app.UITable.Data;
            app.UITable_2.RowName = app.UITable.RowName;
            app.PlayerWinsLabel.Text = strcat(app.playerNames(app.diceGame.currentTopScore), ' Wins!!!');
            app.ScoreLabel.Text = strcat('Score:  ', " ", num2str(app.diceGame.players(app.diceGame.currentTopScore).totalScore));
            if(~(isempty(app.localPlayerIdx)) && app.localPlayerIdx == app.diceGame.currentTopScore)
                app.Image2_2.ImageSource = 'willywonka.gif';
            else
                app.Image2_2.ImageSource = 'willywonkalost.gif';
            end
            app.readTimer.stop();
        end
        
        function gameStart(app)
            app.GamePanel.Visible = 'on';
            app.GamePanel.Title = '';
            app.GamePanel.Enable = 'on';
            app.StartupPanel.Visible = 'off';
            app.StartupPanel.Enable = 'off';
            
            %Create Scorecards based on stored names
            cards = scorecard;
            
            for i = 1:length(app.playerNames)
                cards(i) = scorecard(app.playerNames(i));
            end

            app.UITable.RowName = app.playerNames';
            app.diceGame = game(cards);
            app.diceGame = app.diceGame.startGame();
            app.updateData();
            if(~isempty(app.localPlayerIdx))
                app.PlayerLabel.Text = strcat("Username: ", " ", app.playerNames(app.localPlayerIdx));
            else
                app.PlayerLabel.Text = "Spectator";
            end
        end
        
        %Functions for timerHandles
        function tsWriteScore(app, ~, ~)
            recentScore = app.diceGame.recentlyAddedScore;            
            app.tsHandler.writeScoreIdx(recentScore(1),recentScore(2), recentScore(3), recentScore(4));
            if(app.diceGame.round > app.diceGame.maxRound)
            app.victoryScreen();
            %Consider any kind of ThingSpeak cleanup??
            end
        end
        
        function tsWriteStart(app,~,~)
            app.tsHandler.writeGameStart();
            app.readTimer.TimerFcn = @app.updateScores;
        end
        
        function writeTimerStart(app, ~, ~)
            app.readTimer.stop();
        end
        
        function writeTimerEnd(app, ~, ~)
            if(app.diceGame.round > 1 || app.diceGame.turn > 1)
                app.updateData();
            end
            app.readTimer.start();
        end
    end
    
    methods
        function selected = get.selectedDice(app)
            vals = [app.CheckBox.Value, app.CheckBox_2.Value, app.CheckBox_3.Value, app.CheckBox_4.Value, app.CheckBox_5.Value];
            selected = 1:5;
            selected = selected(vals);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.roller = diceRoller(5,3);
            app.imageFiles = ["unrolled.png", "ones.png","twos.png",...
            "threes.png", "fours.png", "fives.png", "sixes.png"];
            app.diceImages();
            app.CheckBoxValueChanged();
            app.EndTurnButton.Enable = false;
            app.TextArea.Editable = false;
            app.EndgamePanel.Enable = 'off';
            app.EndgamePanel.Visible = 'off';
            app.StartupPanel.Title = '';
            app.StartupPanel.Visible = 'on';
            app.StartupPanel.Enable = 'on';
            app.diceGame = game();
            
            %initialize read timer
            app.readTimer = timer('ExecutionMode','fixedSpacing');
            app.readTimer.period = 1;
            app.readTimer.StartDelay = 1;
            %Start with updatePlayers function
            app.readTimer.TimerFcn = @app.updatePlayers;
            
            
            app.writeTimer = timer('ExecutionMode','singleShot','StartDelay', 1);
            app.writeTimer.StartFcn = @app.writeTimerStart; 
            app.writeTimer.TimerFcn =  @app.tsWriteStart;
            app.writeTimer.StopFcn = @app.writeTimerEnd;
            
            %initialize thingSpeakHandler
            app.tsHandler = thingSpeakHandler();
            
            app.readTimer.start();
        end

        % Button pushed function: RollAllButton
        function RollAllButtonPushed(app, event)
            app.roller.rollAll();
            app.turnEnd();
            app.diceImages();
            playDiceSound();
            if(app.roller.rollCount >= 1)
                app.EndTurnButton.Enable = true;
            end
        end

        % Button pushed function: RollSelectedButton
        function RollSelectedButtonPushed(app, event)
            app.roller.rollSelected(app.selectedDice);
            app.turnEnd();
            app.diceImages();
            playDiceSound();
            if(app.roller.rollCount >= 1)
                app.EndTurnButton.Enable = true;
            end
        end

        % Button pushed function: EndTurnButton
        function EndTurnButtonPushed(app, event)
            % Score dice
            app.EndTurnButton.Enable = false;
            app.RollAllButton.Enable = false;
            %Write score to ThingSpeak and update data
            app.writeScore();           
            app.roller = diceRoller(5,3);
            app.diceImages();
            app.RollAllButton.Enable = true;
            app.RollSelectedButton.Enable = true;
            app.EndTurnButton.BackgroundColor = [0.96, 0.96, 0.96];
            app.Label.Text = num2str(app.roller.rollCount);
            app.CheckBox.Value = false;
            app.CheckBox_2.Value = false;
            app.CheckBox_3.Value = false;
            app.CheckBox_4.Value = false;
            app.CheckBox_5.Value = false;
            app.CheckBoxValueChanged();
        end

        % Value changed function: CheckBox, CheckBox_2, CheckBox_3, 
        % CheckBox_4, CheckBox_5
        function CheckBoxValueChanged(app, event)
            if(isempty(app.selectedDice) || app.roller.rollCount == 0)
                app.RollSelectedButton.Enable = false;
            else
                app.RollSelectedButton.Enable = true;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.gameStart();            
            app.writeTimer.start();
            %wait until writeTimer is done running
        end

        % Button pushed function: JoinGameButton
        function JoinGameButtonPushed(app, event)
            %Writes the name from EnterNameEditField to ThingSpeak and
            %stores the current list in TextArea
            [app.playerNames, app.localPlayerIdx] = app.tsHandler.writeName(app.EnterNameEditField.Value);
            %Start read timer
            app.TextArea.Value = join(app.playerNames, newline);
            app.StartButton.Visible = "on";
            app.StartButton.Enable = "on";
            app.JoinGameButton.Visible = "off";
            app.JoinGameButton.Enable = "off";
            if app.readTimer.Running == "off"
                app.readTimer.start();
            end
        end

        % Button pushed function: QuitGameButton
        function QuitGameButtonPushed(app, event)
            app.readTimer.stop();
            app.writeTimer.stop();

            if(app.diceGame.round > app.diceGame.maxRound)
                tempT = timer("ExecutionMode",'singleShot',"StartDelay",1);
                tempT.timerFcn = @app.resetGame;
                tempT.StopFcn = @tempT.delete;
                
                tempT.start();
            end
            
            app.readTimer.delete();
            app.writeTimer.delete();
            app.delete();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.resetGame();
            app.JoinGameButton.Visible = "on";
            app.JoinGameButton.Enable = "on";
            app.StartButton.Visible = "off";
            app.StartButton.Enable = "off";
        
        end

        % Button pushed function: PlayAgainButtonPushed
        function PlayAgainButtonPushedPushed(app, event)
            app.resetGame();
            app.writeTimer.stop();
            app.readTimer.stop();
            app.writeTimer.delete();
            app.readTimer.delete();
            app.startupFcn();
        end

        % Button pushed function: HowToPlayButton
        function HowToPlayButtonPushed(app, event)
            app.Image3.Enable = ~app.Image3.Visible;
            app.Image3.Visible = ~app.Image3.Visible;
            app.showHelp = ~app.showHelp;
            if(app.showHelp)
                app.HowToPlayButton.Text = 'Close How To Play';
            else
                app.HowToPlayButton.Text = 'How To Play';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EndgamePanel = uipanel(app.UIFigure);
            app.EndgamePanel.Enable = 'off';
            app.EndgamePanel.Title = 'EndgamePanel';
            app.EndgamePanel.Visible = 'off';
            app.GameOverLabel = uilabel(app.EndgamePanel);
            app.GameOverLabel.Text = 'Game Over';
            app.PlayerWinsLabel = uilabel(app.EndgamePanel);
            app.PlayerWinsLabel.Text = 'Player Wins!!!';
            app.ScoreLabel = uilabel(app.EndgamePanel);
            app.ScoreLabel.Text = 'Score: ';
            app.UITable_2 = uitable(app.EndgamePanel);
            app.UITable_2.ColumnName = {'Aces'; 'Deuces'; 'Treys'; 'Fours'; 'Fives'; 'Sixes'; '2 Pair Same Color'; '3 of a Kind'; 'Straight'; 'Flush'; 'Full House'; 'Full House Same Color'; '4 of a Kind'; 'Yarborough'; 'Kismet'; 'Bonus Score'; 'Total Score'};
            app.UITable_2.RowName = {};
            app.PlayAgainButtonPushed = uibutton(app.EndgamePanel, 'push');
            app.PlayAgainButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @PlayAgainButtonPushedPushed, true);
            app.PlayAgainButtonPushed.Text = {'Play Again'; ''};
            app.Image2_2 = uiimage(app.EndgamePanel);
            app.Image2_2.ImageSource = 'willywonka.gif';
            app.GamePanel = uipanel(app.UIFigure);
            app.GamePanel.Enable = 'off';
            app.GamePanel.Title = 'GamePanel';
            app.GamePanel.Visible = 'off';
            app.Image5 = uiimage(app.GamePanel);
            app.Image5.ImageSource = 'gamepanebackground.png';
            app.Image6 = uiimage(app.GamePanel);
            app.Image6.ImageSource = 'gray.jpg';
            app.KismetLabel = uilabel(app.GamePanel);
            app.KismetLabel.Text = 'Kismet';
            app.UITable = uitable(app.GamePanel);
            app.UITable.ColumnName = {'Aces'; 'Deuces'; 'Treys'; 'Fours'; 'Fives'; 'Sixes'; '2 Pair Same Color'; '3 of a Kind'; 'Straight'; 'Flush'; 'Full House'; 'Full House Same Color'; '4 of a Kind'; 'Yarborough'; 'Kismet'; 'Bonus Score'; 'Total Score'};
            app.UITable.RowName = {};
            app.ClickthediceyouwishtorerollLabel = uilabel(app.GamePanel);
            app.ClickthediceyouwishtorerollLabel.Text = 'Click the dice you wish to reroll.';
            app.Image_3 = uiimage(app.GamePanel);
            app.Image_4 = uiimage(app.GamePanel);
            app.Image_5 = uiimage(app.GamePanel);
            app.RollSelectedButton = uibutton(app.GamePanel, 'push');
            app.RollSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @RollSelectedButtonPushed, true);
            app.RollSelectedButton.Text = 'Roll Selected';
            app.EndTurnButton = uibutton(app.GamePanel, 'push');
            app.EndTurnButton.ButtonPushedFcn = createCallbackFcn(app, @EndTurnButtonPushed, true);
            app.EndTurnButton.Text = 'End Turn';
            app.Image_2 = uiimage(app.GamePanel);
            app.Image = uiimage(app.GamePanel);
            app.Label_2 = uilabel(app.GamePanel);
            app.Label_2.Text = {'/3'; ''};
            app.Label = uilabel(app.GamePanel);
            app.Label.Text = '0';
            app.CountLabel = uilabel(app.GamePanel);
            app.CountLabel.Text = 'Count: ';
            app.CheckBox = uicheckbox(app.GamePanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = {''; ''};
            app.CheckBox_2 = uicheckbox(app.GamePanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_2.Text = {''; ''};
            app.CheckBox_3 = uicheckbox(app.GamePanel);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_3.Text = {''; ''};
            app.CheckBox_4 = uicheckbox(app.GamePanel);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_4.Text = {''; ''};
            app.CheckBox_5 = uicheckbox(app.GamePanel);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_5.Text = {''; ''};
            app.TurnLabel = uilabel(app.GamePanel);
            app.TurnLabel.Text = {'Turn '; ''};
            app.RoundLabel = uilabel(app.GamePanel);
            app.RoundLabel.Text = {'Round '; ''};
            app.RollAllButton = uibutton(app.GamePanel, 'push');
            app.RollAllButton.ButtonPushedFcn = createCallbackFcn(app, @RollAllButtonPushed, true);
            app.RollAllButton.Text = 'Roll All';
            app.PlayerLabel = uilabel(app.GamePanel);
            app.PlayerLabel.Text = 'Player';
            app.StartupPanel = uipanel(app.UIFigure);
            app.StartupPanel.Title = 'StartupPanel';
            app.Image4 = uiimage(app.StartupPanel);
            app.Image4.ImageSource = 'background.png';
            app.StartButton = uibutton(app.StartupPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Visible = 'off';
            app.StartButton.Text = 'Play';
            app.EnterNameEditField = uieditfield(app.StartupPanel, 'text');
            app.EnterNameEditField.Value = 'Player 1';
            app.EnterNameEditFieldLabel = uilabel(app.StartupPanel);
            app.EnterNameEditFieldLabel.Text = 'Enter Name';
            app.TextArea = uitextarea(app.StartupPanel);
            app.JoinGameButton = uibutton(app.StartupPanel, 'push');
            app.JoinGameButton.ButtonPushedFcn = createCallbackFcn(app, @JoinGameButtonPushed, true);
            app.JoinGameButton.Text = 'Join Game';
            app.ResetButton = uibutton(app.StartupPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = {'Reset'; ''};
            app.PlayerListLabel = uilabel(app.StartupPanel);
            app.PlayerListLabel.Text = 'Player List';
            app.WelcometoKismetLabel = uilabel(app.StartupPanel);
            app.WelcometoKismetLabel.Text = {'Welcome to Kismet!'; ''};
            app.QuitGameButton = uibutton(app.UIFigure, 'push');
            app.QuitGameButton.ButtonPushedFcn = createCallbackFcn(app, @QuitGameButtonPushed, true);
            app.QuitGameButton.Text = 'Quit Game';
            app.HowToPlayButton = uibutton(app.UIFigure, 'push');
            app.HowToPlayButton.ButtonPushedFcn = createCallbackFcn(app, @HowToPlayButtonPushed, true);
            app.HowToPlayButton.Text = {'How To Play'; ''};
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.Enable = 'off';
            app.Image3.Visible = 'off';
            app.Image3.ImageSource = 'Rules.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiceGameProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end